classdef DoubleChamp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image                   matlab.ui.control.Image
        HealthLabel             matlab.ui.control.Label
        ResourceLabel           matlab.ui.control.Label
        AttackDamageLabel       matlab.ui.control.Label
        ArmorLabel              matlab.ui.control.Label
        AttackSpeedLabel        matlab.ui.control.Label
        CriticalChanceLabel     matlab.ui.control.Label
        HealthVal               matlab.ui.control.Label
        ResourceVal             matlab.ui.control.Label
        AttackDamageVal         matlab.ui.control.Label
        ArmorVal                matlab.ui.control.Label
        AttackSpeedVal          matlab.ui.control.Label
        CriticalChanceVal       matlab.ui.control.Label
        AbilityPowerLabel       matlab.ui.control.Label
        MagicResistanceLabel    matlab.ui.control.Label
        AbilityHasteLabel       matlab.ui.control.Label
        MovementSpeedLabel      matlab.ui.control.Label
        AbilityPowerVal         matlab.ui.control.Label
        MagicResistanceVal      matlab.ui.control.Label
        AbilityHasteVal         matlab.ui.control.Label
        MovementSpeedVal        matlab.ui.control.Label
        Slot11Im                matlab.ui.control.Image
        Slot12Im                matlab.ui.control.Image
        Slot13Im                matlab.ui.control.Image
        Slot14Im                matlab.ui.control.Image
        Slot15Im                matlab.ui.control.Image
        Slot16Im                matlab.ui.control.Image
        Image2                  matlab.ui.control.Image
        Image2_2                matlab.ui.control.Image
        Image2_3                matlab.ui.control.Image
        Image2_4                matlab.ui.control.Image
        Image2_5                matlab.ui.control.Image
        Image2_6                matlab.ui.control.Image
        Image2_7                matlab.ui.control.Image
        Image2_8                matlab.ui.control.Image
        Image2_9                matlab.ui.control.Image
        Image2_10               matlab.ui.control.Image
        Image_2                 matlab.ui.control.Image
        HealthLabel_2           matlab.ui.control.Label
        ResourceLabel_2         matlab.ui.control.Label
        AttackDamageLabel_2     matlab.ui.control.Label
        ArmorLabel_2            matlab.ui.control.Label
        AttackSpeedLabel_2      matlab.ui.control.Label
        CriticalChanceLabel_2   matlab.ui.control.Label
        HealthVal_2             matlab.ui.control.Label
        ResourceVal_2           matlab.ui.control.Label
        AttackDamageVal_2       matlab.ui.control.Label
        ArmorVal_2              matlab.ui.control.Label
        AttackSpeedVal_2        matlab.ui.control.Label
        CriticalChanceVal_2     matlab.ui.control.Label
        AbilityPowerLabel_2     matlab.ui.control.Label
        MagicResistanceLabel_2  matlab.ui.control.Label
        AbilityHasteLabel_2     matlab.ui.control.Label
        MovementSpeedLabel_2    matlab.ui.control.Label
        AbilityPowerVal_2       matlab.ui.control.Label
        MagicResistanceVal_2    matlab.ui.control.Label
        AbilityHasteVal_2       matlab.ui.control.Label
        MovementSpeedVal_2      matlab.ui.control.Label
        Slot21Im                matlab.ui.control.Image
        Slot22Im                matlab.ui.control.Image
        Slot23Im                matlab.ui.control.Image
        Slot24Im                matlab.ui.control.Image
        Slot25Im                matlab.ui.control.Image
        Slot26Im                matlab.ui.control.Image
        Image2_11               matlab.ui.control.Image
        Image2_12               matlab.ui.control.Image
        Image2_13               matlab.ui.control.Image
        Image2_14               matlab.ui.control.Image
        Image2_15               matlab.ui.control.Image
        Image2_16               matlab.ui.control.Image
        Image2_17               matlab.ui.control.Image
        Image2_18               matlab.ui.control.Image
        Image2_19               matlab.ui.control.Image
        Image2_20               matlab.ui.control.Image
        Q1                      matlab.ui.control.Image
        W1                      matlab.ui.control.Image
        E1                      matlab.ui.control.Image
        R1                      matlab.ui.control.Image
        MagicPenLabel           matlab.ui.control.Label
        MagicPenVal             matlab.ui.control.Label
        Image2_21               matlab.ui.control.Image
        ArmorPenLabel           matlab.ui.control.Label
        ArmorPenVal             matlab.ui.control.Label
        Image2_22               matlab.ui.control.Image
        LethalityLabel          matlab.ui.control.Label
        LethalityVal            matlab.ui.control.Label
        Image2_23               matlab.ui.control.Image
        MagicPenLabel_2         matlab.ui.control.Label
        MagicPenVal_2           matlab.ui.control.Label
        Image2_24               matlab.ui.control.Image
        ArmorPenLabel_2         matlab.ui.control.Label
        ArmorPenVal_2           matlab.ui.control.Label
        Image2_25               matlab.ui.control.Image
        LethalityLabel_2        matlab.ui.control.Label
        LethalityVal_2          matlab.ui.control.Label
        Image2_26               matlab.ui.control.Image
        Q2                      matlab.ui.control.Image
        W2                      matlab.ui.control.Image
        E2                      matlab.ui.control.Image
        R2                      matlab.ui.control.Image
        TabGroup                matlab.ui.container.TabGroup
        Tab1                    matlab.ui.container.Tab
        effectAttributeLabel1   matlab.ui.control.Label
        effectValueLabel1       matlab.ui.control.Label
        effectDealtLabel1       matlab.ui.control.Label
        Utility1                matlab.ui.control.Label
        Tab2                    matlab.ui.container.Tab
        effectAttributeLabel2   matlab.ui.control.Label
        effectValueLabel2       matlab.ui.control.Label
        effectDealtLabel2       matlab.ui.control.Label
        Utility2                matlab.ui.control.Label
        Tab3                    matlab.ui.container.Tab
        effectAttributeLabel3   matlab.ui.control.Label
        effectValueLabel3       matlab.ui.control.Label
        effectDealtLabel3       matlab.ui.control.Label
        Utility3                matlab.ui.control.Label
        Tab4                    matlab.ui.container.Tab
        effectAttributeLabel4   matlab.ui.control.Label
        effectValueLabel4       matlab.ui.control.Label
        effectDealtLabel4       matlab.ui.control.Label
        Utility4                matlab.ui.control.Label
        Tab5                    matlab.ui.container.Tab
        effectAttributeLabel5   matlab.ui.control.Label
        effectValueLabel5       matlab.ui.control.Label
        effectDealtLabel5       matlab.ui.control.Label
        Utility5                matlab.ui.control.Label
        Tab6                    matlab.ui.container.Tab
        effectAttributeLabel6   matlab.ui.control.Label
        effectValueLabel6       matlab.ui.control.Label
        effectDealtLabel6       matlab.ui.control.Label
        Utility6                matlab.ui.control.Label
        Tab7                    matlab.ui.container.Tab
        effectAttributeLabel7   matlab.ui.control.Label
        effectValueLabel7       matlab.ui.control.Label
        effectDealtLabel7       matlab.ui.control.Label
        Utility7                matlab.ui.control.Label
        Tab8                    matlab.ui.container.Tab
        effectAttributeLabel8   matlab.ui.control.Label
        effectValueLabel8       matlab.ui.control.Label
        effectDealtLabel8       matlab.ui.control.Label
        Utility8                matlab.ui.control.Label
        Tab9                    matlab.ui.container.Tab
        effectAttributeLabel9   matlab.ui.control.Label
        effectValueLabel9       matlab.ui.control.Label
        effectDealtLabel9       matlab.ui.control.Label
        Utility9                matlab.ui.control.Label
        Tab10                   matlab.ui.container.Tab
        effectAttributeLabel10  matlab.ui.control.Label
        effectValueLabel10      matlab.ui.control.Label
        effectDealtLabel10      matlab.ui.control.Label
        Utility10               matlab.ui.control.Label
        Slot11                  matlab.ui.control.DropDown
        Slot12                  matlab.ui.control.DropDown
        Slot13                  matlab.ui.control.DropDown
        Slot14                  matlab.ui.control.DropDown
        Slot15                  matlab.ui.control.DropDown
        Slot16                  matlab.ui.control.DropDown
        Slot21                  matlab.ui.control.DropDown
        Slot22                  matlab.ui.control.DropDown
        Slot23                  matlab.ui.control.DropDown
        Slot24                  matlab.ui.control.DropDown
        Slot25                  matlab.ui.control.DropDown
        Slot26                  matlab.ui.control.DropDown
        ChampionName            matlab.ui.control.DropDown
        ChampionName2           matlab.ui.control.DropDown
        LvlDropDownLabel        matlab.ui.control.Label
        LvlDrop1                matlab.ui.control.DropDown
        LvlDropDownLabel_2      matlab.ui.control.Label
        LvlDrop2                matlab.ui.control.DropDown
        QSpinnerLabel           matlab.ui.control.Label
        QSpinner1               matlab.ui.control.Spinner
        ESpinnerLabel           matlab.ui.control.Label
        ESpinner1               matlab.ui.control.Spinner
        WSpinnerLabel           matlab.ui.control.Label
        WSpinner1               matlab.ui.control.Spinner
        RSpinnerLabel           matlab.ui.control.Label
        RSpinner1               matlab.ui.control.Spinner
        QSpinner_2Label         matlab.ui.control.Label
        QSpinner2               matlab.ui.control.Spinner
        ESpinner_2Label         matlab.ui.control.Label
        ESpinner2               matlab.ui.control.Spinner
        WSpinner_2Label         matlab.ui.control.Label
        WSpinner2               matlab.ui.control.Spinner
        RSpinner_2Label         matlab.ui.control.Label
        RSpinner2               matlab.ui.control.Spinner
        abi1                    matlab.ui.control.Label
        abi2                    matlab.ui.control.Label
    end

    
    properties (Access = public)
        itemDat  %Item data file
        champDat %Champion data file
        champ1   %Left champion structure
        champ2   %Right champion structure
        side     %Indicator for StatLoad
        itemKey  %Item keys 
        itemName %Item names
        invTemp  %Temporary inventory slot
        slot     %Slot in use
        champ    %Champ structure being referenced
        abiTemp  %Temporary ability slot
        dirSpell %Spell image file directory
        dirChamp %Champion image file directory
        dirGen   %General file directory
        dirItem
    end
    
    methods (Access = public)
        
        function StatLoad(app)
            %This function takes stats out of the champion structures and
            %displays them in the app
            switch app.side
                case 1
                    app.AttackDamageVal.Text = num2str(app.champ1.stats.attackDamage,'%.0f');
                    app.ArmorVal.Text = num2str(app.champ1.stats.armor,'%.0f');
                    app.AttackSpeedVal.Text = num2str(app.champ1.stats.attackSpeed,'%.2f');
                    app.CriticalChanceVal.Text = [num2str(app.champ1.stats.criticalStrikeChance,'%.0f'),'%'];
                    app.AbilityPowerVal.Text = num2str(app.champ1.stats.abilityPower,'%.0f');
                    app.MagicResistanceVal.Text = num2str(app.champ1.stats.magicResistance,'%.0f');
                    app.AbilityHasteVal.Text = num2str(app.champ1.stats.abilityHaste,'%.0f');
                    app.MovementSpeedVal.Text = num2str(app.champ1.stats.movespeed,'%.0f');
                    app.HealthVal.Text = num2str(app.champ1.stats.health,'%.0f');
                    app.ResourceVal.Text = num2str(app.champ1.stats.mana,'%0.f');
                    app.MagicPenVal.Text = [num2str(app.champ1.stats.magicPenetration,'%.0f'),'/',...
                        num2str(app.champ1.stats.magicPenPer,'%.0f'),'%'];
                    app.ArmorPenVal.Text = [num2str(app.champ1.stats.armorPenetration,'%.0f'),'%'];
                    app.LethalityVal.Text = num2str(app.champ1.stats.lethality,'%.0f');
                case 2
                    app.AttackDamageVal_2.Text = num2str(app.champ2.stats.attackDamage,'%.0f');
                    app.ArmorVal_2.Text = num2str(app.champ2.stats.armor,'%.0f');
                    app.AttackSpeedVal_2.Text = num2str(app.champ2.stats.attackSpeed,'%.2f');
                    app.CriticalChanceVal_2.Text = [num2str(app.champ2.stats.criticalStrikeChance,'%.0f'),'%'];
                    app.AbilityPowerVal_2.Text = num2str(app.champ2.stats.abilityPower,'%.0f');
                    app.MagicResistanceVal_2.Text = num2str(app.champ2.stats.magicResistance,'%.0f');
                    app.AbilityHasteVal_2.Text = num2str(app.champ2.stats.abilityHaste,'%.0f');
                    app.MovementSpeedVal_2.Text = num2str(app.champ2.stats.movespeed,'%.0f');
                    app.HealthVal_2.Text = num2str(app.champ2.stats.health,'%.0f');
                    app.ResourceVal_2.Text = num2str(app.champ2.stats.mana,'%0.f');
                    app.MagicPenVal_2.Text = [num2str(app.champ2.stats.magicPenetration,'%.0f'),'/',...
                        num2str(app.champ2.stats.magicPenPer,'%.0f'),'%'];
                    app.ArmorPenVal_2.Text = [num2str(app.champ2.stats.armorPenetration,'%.0f'),'%'];
                    app.LethalityVal_2.Text = num2str(app.champ2.stats.lethality,'%.0f');
            end
        end
        
        function ItemLoad(app)
            %This function processes user input in order to add items to
            %champion structure inventories. It also updates images.
            slotImName = ['Slot',num2str(app.side),num2str(app.slot),'Im'];
            if strcmp(app.invTemp,'None')
                app.(app.champ) = ItemRemove(app.itemDat,app.(app.champ),app.slot);
                app.(slotImName).ImageSource = [app.dirGen,'square.png'];
            else
                ind = strcmp(app.itemName,app.invTemp);
                item = app.itemKey(ind);

                app.(app.champ) = ItemRemove(app.itemDat,app.(app.champ),app.slot);
                app.(app.champ) = ItemAdd(app.itemDat,app.(app.champ),item,app.slot);
                app.(slotImName).ImageSource = [app.dirItem,num2str(item),'.png'];
            end
            app.(app.champ) = PassiveUp(app.itemDat,app.(app.champ));
            StatLoad(app)
        end
        
        function AbiLoad(app)
            %This function updates ability icons and structure level by
            %interpreting user input
            spent = 0;
            abi = 'QWER';
            for i = 1:length(abi)
                spent = spent + app.(app.champ).abi.(abi(i));
            end
            for i = 1:length(abi)
                switch abi(i)
                    case 'R'
                        maxLevel = floor((app.(app.champ).l-1)/5);
                    otherwise
                        maxLevel = ceil(app.(app.champ).l/2);
                end
                if app.(app.champ).l - spent == 0
                    maxLevel = app.(app.champ).abi.(abi(i));
                end
                if maxLevel == 0
                    app.([abi(i),'Spinner',num2str(app.side)]).Visible = 0;
                else
                    app.([abi(i),'Spinner',num2str(app.side)]).Visible = 1;
                    app.([abi(i),'Spinner',num2str(app.side)]).Limits = [0,maxLevel];
                end
            end
            switch app.(app.champ).l - spent
                case 0
                    app.(['abi',num2str(app.side)]).Text = 'No points left to spend!';
                case 1
                    app.(['abi',num2str(app.side)]).Text = '1 point to spend!';
                otherwise
                    app.(['abi',num2str(app.side)]).Text = [num2str(app.(app.champ).l - spent),' points to spend!'];
            end
        end
        
        function EffectLoad(app)
            %This function is triggered when an ability icon is pressed and
            %in turn calls AbilityVal. The output structure is interpretted
            %into the UI
            if app.side == 1
                [effect,~,app.champ1,app.champ2] = AbilityVal(app.champDat,app.champ1,app.abiTemp,app.champ2);
            elseif app.side == 2
                [effect,~,app.champ2,app.champ1] = AbilityVal(app.champDat,app.champ2,app.abiTemp,app.champ1);
            end
            si = {'2','1'};
            tabCount = 0;
            for i = 1:numel(effect)
                if ~isempty(effect(i).value)
                    tabCount = tabCount + 1;
                    app.(['Tab',num2str(tabCount)]).Tooltip = effect(i).att;
                    app.(['Tab',num2str(tabCount)]).Title = effect(i).att;
                    app.(['Utility',num2str(tabCount)]).Visible = 0;
                    
                    app.(['effectAttributeLabel',num2str(tabCount)]).Visible = 1;
                    app.(['effectAttributeLabel',num2str(tabCount)]).Text = effect(i).att;
                    
                    app.(['effectValueLabel',num2str(tabCount)]).Visible = 1;
                    app.(['effectValueLabel',num2str(tabCount)]).Text = num2str(effect(i).value,'%.1f');
                    
                    if contains(effect(i).att,'amage')
                        app.(['effectDealtLabel',num2str(tabCount)]).Visible = 1;
                        app.(['effectDealtLabel',num2str(tabCount)]).Text = [app.(['champ',si{app.side}]).ch,' would take ',...
                            num2str(effect(i).dealt,'%.1f'), ' damage.'];
                    else
                        app.(['effectDealtLabel',num2str(i)]).Visible = 0;
                    end
                else
                    app.(['Utility',num2str(i)]).Visible = 1;
                end
            end
            for i = tabCount+1:10
                app.(['Tab',num2str(i)]).Tooltip = '';
                app.(['Tab',num2str(i)]).Title = '';
                app.(['Utility',num2str(i)]).Visible = 0;
                app.(['effectAttributeLabel',num2str(i)]).Visible = 0;
                app.(['effectValueLabel',num2str(i)]).Visible = 0;
                app.(['effectDealtLabel',num2str(i)]).Visible = 0;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load itemdat.mat itemdat itemkey itemname
            load champdat.mat champdat
            app.champDat = champdat;
            app.itemDat = itemdat;
            app.itemName = itemname;
            app.itemKey = itemkey;
            app.dirChamp = '/Users/ethangreen/Documents/MATLAB/Dragon/Champion/'; %Champion directory here
            app.dirSpell = '/Users/ethangreen/Documents/MATLAB/Dragon/spell/';    %Spell directory here
            app.dirGen = '/Users/ethangreen/Documents/MATLAB/Dragon/';            %General directory here
            app.dirItem = '/Users/ethangreen/Documents/MATLAB/Dragon/item/';      %Item directory here
            ChampGen1(app)
            ChampGen2(app)
        end

        % Value changed function: ChampionName
        function ChampGen1(app, event)
            ch = app.ChampionName.Value;
            app.champ1 = ChampGen(app.itemDat,app.champDat,ch);
            app.Image.ImageSource = [app.dirChamp,ch,'.png'];
            app.champ = 'champ1'; app.side = 1;
            abi = 'QWER';
            for i = 1:length(abi)
                app.([abi(i),'1']).ImageSource = [app.dirSpell,ch,abi(i),'.png'];
                app.champ1.abi.(abi(i)) = 0;
                app.([abi(i),'Spinner1']).Value = 0;
            end
            for i = 1:6
                app.(['Slot1',num2str(i)]).Value = 'None';
                slotImName = ['Slot',num2str(app.side),num2str(i),'Im'];
                app.(slotImName).ImageSource = [app.dirGen,'square.png']; 
            end
            LevelUp1(app)
        end

        % Value changed function: ChampionName2
        function ChampGen2(app, event)
            ch = app.ChampionName2.Value;
            app.champ2 = ChampGen(app.itemDat,app.champDat,ch);
            app.Image_2.ImageSource = [app.dirChamp,ch,'.png'];
            app.champ = 'champ2'; app.side = 2;
            abi = 'QWER';
            for i = 1:length(abi)
                app.([abi(i),'2']).ImageSource = [app.dirSpell,ch,abi(i),'.png'];
                app.champ2.abi.(abi(i)) = 0;
                app.([abi(i),'Spinner2']).Value = 0;
            end
            for i = 1:6
                app.(['Slot2',num2str(i)]).Value = 'None';
                slotImName = ['Slot',num2str(app.side),num2str(i),'Im'];
                app.(slotImName).ImageSource = [app.dirGen,'square.png'];
            end
            LevelUp2(app)
        end

        % Value changed function: LvlDrop1
        function LevelUp1(app, event)
            l = str2double(app.LvlDrop1.Value);
            abi = 'QWER';
            if l < app.champ1.l
                for i = 1:length(abi)
                    app.champ1.abi.(abi(i)) = 0;
                    app.([abi(i),'Spinner1']).Value = 0;
                end
            end
            app.champ1 = LevelUp(app.champDat,app.champ1,l);
            app.champ = 'champ1'; app.side = 1;
            AbiLoad(app)
            StatLoad(app)
        end

        % Value changed function: LvlDrop2
        function LevelUp2(app, event)
            l = str2double(app.LvlDrop2.Value);
            abi = 'QWER';
            if l < app.champ2.l
                for i = 1:length(abi)
                    app.champ2.abi.(abi(i)) = 0;
                    app.([abi(i),'Spinner2']).Value = 0;
                end
            end
            app.champ2 = LevelUp(app.champDat,app.champ2,l);
            app.champ = 'champ2'; app.side = 2;
            AbiLoad(app)
            StatLoad(app)
        end

        % Value changed function: Slot11
        function Inv11(app, event)
            app.invTemp = app.Slot11.Value;
            app.champ = 'champ1'; app.slot = 1; app.side = 1;
            ItemLoad(app);
        end

        % Value changed function: Slot12
        function Inv12(app, event)
            app.invTemp = app.Slot12.Value;
            app.champ = 'champ1'; app.slot = 2; app.side = 1;
            ItemLoad(app);
        end

        % Value changed function: Slot13
        function Inv13(app, event)
            app.invTemp = app.Slot13.Value;
            app.champ = 'champ1'; app.slot = 3; app.side = 1;
            ItemLoad(app);
        end

        % Value changed function: Slot14
        function Inv14(app, event)
            app.invTemp = app.Slot14.Value;
            app.champ = 'champ1'; app.slot = 4; app.side = 1;
            ItemLoad(app);
        end

        % Value changed function: Slot15
        function Inv15(app, event)
            app.invTemp = app.Slot15.Value;
            app.champ = 'champ1'; app.slot = 5; app.side = 1;
            ItemLoad(app);
        end

        % Value changed function: Slot16
        function Inv16(app, event)
            app.invTemp = app.Slot16.Value;
            app.champ = 'champ1'; app.slot = 6; app.side = 1;
            ItemLoad(app);
        end

        % Value changed function: Slot21
        function Inv21(app, event)
            app.invTemp = app.Slot21.Value;
            app.champ = 'champ2'; app.slot = 1; app.side = 2;
            ItemLoad(app);
        end

        % Value changed function: Slot22
        function Inv22(app, event)
            app.invTemp = app.Slot22.Value;
            app.champ = 'champ2'; app.slot = 2; app.side = 2;
            ItemLoad(app);
        end

        % Value changed function: Slot23
        function Inv23(app, event)
            app.invTemp = app.Slot23.Value;
            app.champ = 'champ2'; app.slot = 3; app.side = 2;
            ItemLoad(app);
        end

        % Value changed function: Slot24
        function Inv24(app, event)
            app.invTemp = app.Slot24.Value;
            app.champ = 'champ2'; app.slot = 4; app.side = 2;
            ItemLoad(app);
        end

        % Value changed function: Slot25
        function Inv25(app, event)
            app.invTemp = app.Slot25.Value;
            app.champ = 'champ2'; app.slot = 5; app.side = 2;
            ItemLoad(app);
        end

        % Value changed function: Slot26
        function Inv26(app, event)
            app.invTemp = app.Slot26.Value;
            app.champ = 'champ2'; app.slot = 6; app.side = 2;
            ItemLoad(app);
        end

        % Value changed function: ESpinner1, ESpinner2, QSpinner1, 
        % QSpinner2, RSpinner1, RSpinner2, WSpinner1, WSpinner2
        function AbiUpdate(app, event)
            app.champ1.abi.Q = app.QSpinner1.Value;
            app.champ1.abi.W = app.WSpinner1.Value;
            app.champ1.abi.E = app.ESpinner1.Value;
            app.champ1.abi.R = app.RSpinner1.Value;
            app.side = 1; app.champ = 'champ1';
            AbiLoad(app)
            
            app.champ2.abi.Q = app.QSpinner2.Value;
            app.champ2.abi.W = app.WSpinner2.Value;
            app.champ2.abi.E = app.ESpinner2.Value;
            app.champ2.abi.R = app.RSpinner2.Value;
            app.side = 2; app.champ = 'champ2';
            AbiLoad(app)
        end

        % Image clicked function: Q1
        function Q1ImageClicked(app, event)
            app.abiTemp = 'Q';
            app.side = 1; app.champ = 'champ1';
            if app.(app.champ).abi.(app.abiTemp) == 0
                app.Utility1.Text = 'Make sure to level an ability before simulating!';
            else
                EffectLoad(app)
            end
        end

        % Image clicked function: W1
        function W1ImageClicked(app, event)
            app.abiTemp = 'W';
            app.side = 1; app.champ = 'champ1';
            if app.(app.champ).abi.(app.abiTemp) == 0
                app.Utility1.Text = 'Make sure to level an ability before simulating!';
            else
                EffectLoad(app)
            end
        end

        % Image clicked function: E1
        function E1ImageClicked(app, event)
            app.abiTemp = 'E';
            app.side = 1; app.champ = 'champ1';
            if app.(app.champ).abi.(app.abiTemp) == 0
                app.Utility1.Text = 'Make sure to level an ability before simulating!';
            else
                EffectLoad(app)
            end
        end

        % Image clicked function: R1
        function R1ImageClicked(app, event)
            app.abiTemp = 'R';
            app.side = 1; app.champ = 'champ1';
            if app.(app.champ).abi.(app.abiTemp) == 0
                app.Utility1.Text = 'Make sure to level an ability before simulating!';
            else
                EffectLoad(app)
            end
        end

        % Image clicked function: Q2
        function Q2ImageClicked(app, event)
            app.abiTemp = 'Q';
            app.side = 2; app.champ = 'champ2';
            if app.(app.champ).abi.(app.abiTemp) == 0
                app.Utility1.Text = 'Make sure to level an ability before simulating!';
            else
                EffectLoad(app)
            end
        end

        % Image clicked function: W2
        function W2ImageClicked(app, event)
            app.abiTemp = 'W';
            app.side = 2; app.champ = 'champ2';
            if app.(app.champ).abi.(app.abiTemp) == 0
                app.Utility1.Text = 'Make sure to level an ability before simulating!';
            else
                EffectLoad(app)
            end
        end

        % Image clicked function: E2
        function E2ImageClicked(app, event)
            app.abiTemp = 'E';
            app.side = 2; app.champ = 'champ2';
            if app.(app.champ).abi.(app.abiTemp) == 0
                app.Utility1.Text = 'Make sure to level an ability before simulating!';
            else
                EffectLoad(app)
            end
        end

        % Image clicked function: R2
        function R2ImageClicked(app, event)
            app.abiTemp = 'R';
            app.side = 2; app.champ = 'champ2';
            if app.(app.champ).abi.(app.abiTemp) == 0
                app.Utility1.Text = 'Make sure to level an ability before simulating!';
            else
                EffectLoad(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.HealthLabel = uilabel(app.UIFigure);
            app.HealthLabel.Text = 'Health';
            app.ResourceLabel = uilabel(app.UIFigure);
            app.ResourceLabel.Text = 'Resource';
            app.AttackDamageLabel = uilabel(app.UIFigure);
            app.AttackDamageLabel.Text = 'Attack Damage';
            app.ArmorLabel = uilabel(app.UIFigure);
            app.ArmorLabel.Text = 'Armor';
            app.AttackSpeedLabel = uilabel(app.UIFigure);
            app.AttackSpeedLabel.Text = 'Attack Speed';
            app.CriticalChanceLabel = uilabel(app.UIFigure);
            app.CriticalChanceLabel.Text = 'Critical Chance';
            app.HealthVal = uilabel(app.UIFigure);
            app.HealthVal.Text = '602';
            app.ResourceVal = uilabel(app.UIFigure);
            app.ResourceVal.Text = '0';
            app.AttackDamageVal = uilabel(app.UIFigure);
            app.AttackDamageVal.Text = '58';
            app.ArmorVal = uilabel(app.UIFigure);
            app.ArmorVal.Text = '28';
            app.AttackSpeedVal = uilabel(app.UIFigure);
            app.AttackSpeedVal.Text = '0.66';
            app.CriticalChanceVal = uilabel(app.UIFigure);
            app.CriticalChanceVal.Text = '0%';
            app.AbilityPowerLabel = uilabel(app.UIFigure);
            app.AbilityPowerLabel.Text = 'Ability Power';
            app.MagicResistanceLabel = uilabel(app.UIFigure);
            app.MagicResistanceLabel.Text = 'Magic Resistance';
            app.AbilityHasteLabel = uilabel(app.UIFigure);
            app.AbilityHasteLabel.Text = 'Ability Haste';
            app.MovementSpeedLabel = uilabel(app.UIFigure);
            app.MovementSpeedLabel.Text = 'Movement Speed';
            app.AbilityPowerVal = uilabel(app.UIFigure);
            app.AbilityPowerVal.Text = '0';
            app.MagicResistanceVal = uilabel(app.UIFigure);
            app.MagicResistanceVal.Text = '32';
            app.AbilityHasteVal = uilabel(app.UIFigure);
            app.AbilityHasteVal.Text = '0';
            app.MovementSpeedVal = uilabel(app.UIFigure);
            app.MovementSpeedVal.Text = '340';
            app.Slot11Im = uiimage(app.UIFigure);
            app.Slot11Im.ImageSource = 'square.png';
            app.Slot12Im = uiimage(app.UIFigure);
            app.Slot12Im.ImageSource = 'square.png';
            app.Slot13Im = uiimage(app.UIFigure);
            app.Slot13Im.ImageSource = 'square.png';
            app.Slot14Im = uiimage(app.UIFigure);
            app.Slot14Im.ImageSource = 'square.png';
            app.Slot15Im = uiimage(app.UIFigure);
            app.Slot15Im.ImageSource = 'square.png';
            app.Slot16Im = uiimage(app.UIFigure);
            app.Slot16Im.ImageSource = 'square.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'Health_icon.png';
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.ImageSource = 'Mana_icon.png';
            app.Image2_3 = uiimage(app.UIFigure);
            app.Image2_3.ImageSource = 'Attack_damage_icon.png';
            app.Image2_4 = uiimage(app.UIFigure);
            app.Image2_4.ImageSource = 'Armor_icon.png';
            app.Image2_5 = uiimage(app.UIFigure);
            app.Image2_5.ImageSource = 'Attack_speed_icon.png';
            app.Image2_6 = uiimage(app.UIFigure);
            app.Image2_6.ImageSource = 'Critical_strike_damage_icon.png';
            app.Image2_7 = uiimage(app.UIFigure);
            app.Image2_7.ImageSource = 'Ability_power_icon.png';
            app.Image2_8 = uiimage(app.UIFigure);
            app.Image2_8.ImageSource = 'Magic_resistance_icon.png';
            app.Image2_9 = uiimage(app.UIFigure);
            app.Image2_9.ImageSource = 'Cooldown_reduction_icon.png';
            app.Image2_10 = uiimage(app.UIFigure);
            app.Image2_10.ImageSource = 'Movement_speed_icon.png';
            app.Image_2 = uiimage(app.UIFigure);
            app.HealthLabel_2 = uilabel(app.UIFigure);
            app.HealthLabel_2.Text = 'Health';
            app.ResourceLabel_2 = uilabel(app.UIFigure);
            app.ResourceLabel_2.Text = 'Resource';
            app.AttackDamageLabel_2 = uilabel(app.UIFigure);
            app.AttackDamageLabel_2.Text = 'Attack Damage';
            app.ArmorLabel_2 = uilabel(app.UIFigure);
            app.ArmorLabel_2.Text = 'Armor';
            app.AttackSpeedLabel_2 = uilabel(app.UIFigure);
            app.AttackSpeedLabel_2.Text = 'Attack Speed';
            app.CriticalChanceLabel_2 = uilabel(app.UIFigure);
            app.CriticalChanceLabel_2.Text = 'Critical Chance';
            app.HealthVal_2 = uilabel(app.UIFigure);
            app.HealthVal_2.Text = '602';
            app.ResourceVal_2 = uilabel(app.UIFigure);
            app.ResourceVal_2.Text = '0';
            app.AttackDamageVal_2 = uilabel(app.UIFigure);
            app.AttackDamageVal_2.Text = '58';
            app.ArmorVal_2 = uilabel(app.UIFigure);
            app.ArmorVal_2.Text = '28';
            app.AttackSpeedVal_2 = uilabel(app.UIFigure);
            app.AttackSpeedVal_2.Text = '0.66';
            app.CriticalChanceVal_2 = uilabel(app.UIFigure);
            app.CriticalChanceVal_2.Text = '0%';
            app.AbilityPowerLabel_2 = uilabel(app.UIFigure);
            app.AbilityPowerLabel_2.Text = 'Ability Power';
            app.MagicResistanceLabel_2 = uilabel(app.UIFigure);
            app.MagicResistanceLabel_2.Text = 'Magic Resistance';
            app.AbilityHasteLabel_2 = uilabel(app.UIFigure);
            app.AbilityHasteLabel_2.Text = 'Ability Haste';
            app.MovementSpeedLabel_2 = uilabel(app.UIFigure);
            app.MovementSpeedLabel_2.Text = 'Movement Speed';
            app.AbilityPowerVal_2 = uilabel(app.UIFigure);
            app.AbilityPowerVal_2.Text = '0';
            app.MagicResistanceVal_2 = uilabel(app.UIFigure);
            app.MagicResistanceVal_2.Text = '32';
            app.AbilityHasteVal_2 = uilabel(app.UIFigure);
            app.AbilityHasteVal_2.Text = '0';
            app.MovementSpeedVal_2 = uilabel(app.UIFigure);
            app.MovementSpeedVal_2.Text = '340';
            app.Slot21Im = uiimage(app.UIFigure);
            app.Slot21Im.ImageSource = 'square.png';
            app.Slot22Im = uiimage(app.UIFigure);
            app.Slot22Im.ImageSource = 'square.png';
            app.Slot23Im = uiimage(app.UIFigure);
            app.Slot23Im.ImageSource = 'square.png';
            app.Slot24Im = uiimage(app.UIFigure);
            app.Slot24Im.ImageSource = 'square.png';
            app.Slot25Im = uiimage(app.UIFigure);
            app.Slot25Im.ImageSource = 'square.png';
            app.Slot26Im = uiimage(app.UIFigure);
            app.Slot26Im.ImageSource = 'square.png';
            app.Image2_11 = uiimage(app.UIFigure);
            app.Image2_11.ImageSource = 'Health_icon.png';
            app.Image2_12 = uiimage(app.UIFigure);
            app.Image2_12.ImageSource = 'Mana_icon.png';
            app.Image2_13 = uiimage(app.UIFigure);
            app.Image2_13.ImageSource = 'Attack_damage_icon.png';
            app.Image2_14 = uiimage(app.UIFigure);
            app.Image2_14.ImageSource = 'Armor_icon.png';
            app.Image2_15 = uiimage(app.UIFigure);
            app.Image2_15.ImageSource = 'Attack_speed_icon.png';
            app.Image2_16 = uiimage(app.UIFigure);
            app.Image2_16.ImageSource = 'Critical_strike_damage_icon.png';
            app.Image2_17 = uiimage(app.UIFigure);
            app.Image2_17.ImageSource = 'Ability_power_icon.png';
            app.Image2_18 = uiimage(app.UIFigure);
            app.Image2_18.ImageSource = 'Magic_resistance_icon.png';
            app.Image2_19 = uiimage(app.UIFigure);
            app.Image2_19.ImageSource = 'Cooldown_reduction_icon.png';
            app.Image2_20 = uiimage(app.UIFigure);
            app.Image2_20.ImageSource = 'Movement_speed_icon.png';
            app.Q1 = uiimage(app.UIFigure);
            app.Q1.ImageClickedFcn = createCallbackFcn(app, @Q1ImageClicked, true);
            app.W1 = uiimage(app.UIFigure);
            app.W1.ImageClickedFcn = createCallbackFcn(app, @W1ImageClicked, true);
            app.E1 = uiimage(app.UIFigure);
            app.E1.ImageClickedFcn = createCallbackFcn(app, @E1ImageClicked, true);
            app.R1 = uiimage(app.UIFigure);
            app.R1.ImageClickedFcn = createCallbackFcn(app, @R1ImageClicked, true);
            app.MagicPenLabel = uilabel(app.UIFigure);
            app.MagicPenLabel.Text = 'Magic Pen';
            app.MagicPenVal = uilabel(app.UIFigure);
            app.MagicPenVal.Text = '0/0%';
            app.Image2_21 = uiimage(app.UIFigure);
            app.Image2_21.ImageSource = 'Magic_penetration_icon.png';
            app.ArmorPenLabel = uilabel(app.UIFigure);
            app.ArmorPenLabel.Text = 'Armor Pen';
            app.ArmorPenVal = uilabel(app.UIFigure);
            app.ArmorPenVal.Text = '0%';
            app.Image2_22 = uiimage(app.UIFigure);
            app.Image2_22.ImageSource = 'Armor_penetration_icon.png';
            app.LethalityLabel = uilabel(app.UIFigure);
            app.LethalityLabel.Text = 'Lethality';
            app.LethalityVal = uilabel(app.UIFigure);
            app.LethalityVal.Text = '0';
            app.Image2_23 = uiimage(app.UIFigure);
            app.Image2_23.ImageSource = 'Armor_penetration_icon.png';
            app.MagicPenLabel_2 = uilabel(app.UIFigure);
            app.MagicPenLabel_2.Text = 'Magic Pen';
            app.MagicPenVal_2 = uilabel(app.UIFigure);
            app.MagicPenVal_2.Text = '0/0%';
            app.Image2_24 = uiimage(app.UIFigure);
            app.Image2_24.ImageSource = 'Magic_penetration_icon.png';
            app.ArmorPenLabel_2 = uilabel(app.UIFigure);
            app.ArmorPenLabel_2.Text = 'Armor Pen';
            app.ArmorPenVal_2 = uilabel(app.UIFigure);
            app.ArmorPenVal_2.Text = '0%';
            app.Image2_25 = uiimage(app.UIFigure);
            app.Image2_25.ImageSource = 'Armor_penetration_icon.png';
            app.LethalityLabel_2 = uilabel(app.UIFigure);
            app.LethalityLabel_2.Text = 'Lethality';
            app.LethalityVal_2 = uilabel(app.UIFigure);
            app.LethalityVal_2.Text = '0';
            app.Image2_26 = uiimage(app.UIFigure);
            app.Image2_26.ImageSource = 'Armor_penetration_icon.png';
            app.Q2 = uiimage(app.UIFigure);
            app.Q2.ImageClickedFcn = createCallbackFcn(app, @Q2ImageClicked, true);
            app.W2 = uiimage(app.UIFigure);
            app.W2.ImageClickedFcn = createCallbackFcn(app, @W2ImageClicked, true);
            app.E2 = uiimage(app.UIFigure);
            app.E2.ImageClickedFcn = createCallbackFcn(app, @E2ImageClicked, true);
            app.R2 = uiimage(app.UIFigure);
            app.R2.ImageClickedFcn = createCallbackFcn(app, @R2ImageClicked, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab1 = uitab(app.TabGroup);
            app.effectAttributeLabel1 = uilabel(app.Tab1);
            app.effectAttributeLabel1.Visible = 'off';
            app.effectAttributeLabel1.Text = 'effectAttribute';
            app.effectValueLabel1 = uilabel(app.Tab1);
            app.effectValueLabel1.Visible = 'off';
            app.effectValueLabel1.Text = 'effectValue';
            app.effectDealtLabel1 = uilabel(app.Tab1);
            app.effectDealtLabel1.Visible = 'off';
            app.effectDealtLabel1.Text = 'effectDealt';
            app.Utility1 = uilabel(app.Tab1);
            app.Utility1.Text = 'Press an ability to simulate!';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Tooltip = {''};
            app.effectAttributeLabel2 = uilabel(app.Tab2);
            app.effectAttributeLabel2.Visible = 'off';
            app.effectAttributeLabel2.Text = 'effectAttribute';
            app.effectValueLabel2 = uilabel(app.Tab2);
            app.effectValueLabel2.Visible = 'off';
            app.effectValueLabel2.Text = 'effectValue';
            app.effectDealtLabel2 = uilabel(app.Tab2);
            app.effectDealtLabel2.Visible = 'off';
            app.effectDealtLabel2.Text = 'effectDealt';
            app.Utility2 = uilabel(app.Tab2);
            app.Utility2.Visible = 'off';
            app.Utility2.Text = 'This is a utility ability!';
            app.Tab3 = uitab(app.TabGroup);
            app.effectAttributeLabel3 = uilabel(app.Tab3);
            app.effectAttributeLabel3.Visible = 'off';
            app.effectAttributeLabel3.Text = 'effectAttribute';
            app.effectValueLabel3 = uilabel(app.Tab3);
            app.effectValueLabel3.Visible = 'off';
            app.effectValueLabel3.Text = 'effectValue';
            app.effectDealtLabel3 = uilabel(app.Tab3);
            app.effectDealtLabel3.Visible = 'off';
            app.effectDealtLabel3.Text = 'effectDealt';
            app.Utility3 = uilabel(app.Tab3);
            app.Utility3.Visible = 'off';
            app.Utility3.Text = 'This is a utility ability!';
            app.Tab4 = uitab(app.TabGroup);
            app.effectAttributeLabel4 = uilabel(app.Tab4);
            app.effectAttributeLabel4.Visible = 'off';
            app.effectAttributeLabel4.Text = 'effectAttribute';
            app.effectValueLabel4 = uilabel(app.Tab4);
            app.effectValueLabel4.Visible = 'off';
            app.effectValueLabel4.Text = 'effectValue';
            app.effectDealtLabel4 = uilabel(app.Tab4);
            app.effectDealtLabel4.Visible = 'off';
            app.effectDealtLabel4.Text = 'effectDealt';
            app.Utility4 = uilabel(app.Tab4);
            app.Utility4.Visible = 'off';
            app.Utility4.Text = 'This is a utility ability!';
            app.Tab5 = uitab(app.TabGroup);
            app.effectAttributeLabel5 = uilabel(app.Tab5);
            app.effectAttributeLabel5.Visible = 'off';
            app.effectAttributeLabel5.Text = 'effectAttribute';
            app.effectValueLabel5 = uilabel(app.Tab5);
            app.effectValueLabel5.Visible = 'off';
            app.effectValueLabel5.Text = 'effectValue';
            app.effectDealtLabel5 = uilabel(app.Tab5);
            app.effectDealtLabel5.Visible = 'off';
            app.effectDealtLabel5.Text = 'effectDealt';
            app.Utility5 = uilabel(app.Tab5);
            app.Utility5.Visible = 'off';
            app.Utility5.Text = 'This is a utility ability!';
            app.Tab6 = uitab(app.TabGroup);
            app.effectAttributeLabel6 = uilabel(app.Tab6);
            app.effectAttributeLabel6.Visible = 'off';
            app.effectAttributeLabel6.Text = 'effectAttribute';
            app.effectValueLabel6 = uilabel(app.Tab6);
            app.effectValueLabel6.Visible = 'off';
            app.effectValueLabel6.Text = 'effectValue';
            app.effectDealtLabel6 = uilabel(app.Tab6);
            app.effectDealtLabel6.Visible = 'off';
            app.effectDealtLabel6.Text = 'effectDealt';
            app.Utility6 = uilabel(app.Tab6);
            app.Utility6.Visible = 'off';
            app.Utility6.Text = 'This is a utility ability!';
            app.Tab7 = uitab(app.TabGroup);
            app.effectAttributeLabel7 = uilabel(app.Tab7);
            app.effectAttributeLabel7.Visible = 'off';
            app.effectAttributeLabel7.Text = 'effectAttribute';
            app.effectValueLabel7 = uilabel(app.Tab7);
            app.effectValueLabel7.Visible = 'off';
            app.effectValueLabel7.Text = 'effectValue';
            app.effectDealtLabel7 = uilabel(app.Tab7);
            app.effectDealtLabel7.Visible = 'off';
            app.effectDealtLabel7.Text = 'effectDealt';
            app.Utility7 = uilabel(app.Tab7);
            app.Utility7.Visible = 'off';
            app.Utility7.Text = 'This is a utility ability!';
            app.Tab8 = uitab(app.TabGroup);
            app.effectAttributeLabel8 = uilabel(app.Tab8);
            app.effectAttributeLabel8.Visible = 'off';
            app.effectAttributeLabel8.Text = 'effectAttribute';
            app.effectValueLabel8 = uilabel(app.Tab8);
            app.effectValueLabel8.Visible = 'off';
            app.effectValueLabel8.Text = 'effectValue';
            app.effectDealtLabel8 = uilabel(app.Tab8);
            app.effectDealtLabel8.Visible = 'off';
            app.effectDealtLabel8.Text = 'effectDealt';
            app.Utility8 = uilabel(app.Tab8);
            app.Utility8.Visible = 'off';
            app.Utility8.Text = 'This is a utility ability!';
            app.Tab9 = uitab(app.TabGroup);
            app.effectAttributeLabel9 = uilabel(app.Tab9);
            app.effectAttributeLabel9.Visible = 'off';
            app.effectAttributeLabel9.Text = 'effectAttribute';
            app.effectValueLabel9 = uilabel(app.Tab9);
            app.effectValueLabel9.Visible = 'off';
            app.effectValueLabel9.Text = 'effectValue';
            app.effectDealtLabel9 = uilabel(app.Tab9);
            app.effectDealtLabel9.Visible = 'off';
            app.effectDealtLabel9.Text = 'effectDealt';
            app.Utility9 = uilabel(app.Tab9);
            app.Utility9.Visible = 'off';
            app.Utility9.Text = 'This is a utility ability!';
            app.Tab10 = uitab(app.TabGroup);
            app.effectAttributeLabel10 = uilabel(app.Tab10);
            app.effectAttributeLabel10.Visible = 'off';
            app.effectAttributeLabel10.Text = 'effectAttribute';
            app.effectValueLabel10 = uilabel(app.Tab10);
            app.effectValueLabel10.Visible = 'off';
            app.effectValueLabel10.Text = 'effectValue';
            app.effectDealtLabel10 = uilabel(app.Tab10);
            app.effectDealtLabel10.Visible = 'off';
            app.effectDealtLabel10.Text = 'effectDealt';
            app.Utility10 = uilabel(app.Tab10);
            app.Utility10.Visible = 'off';
            app.Utility10.Text = 'This is a utility ability!';
            app.Slot11 = uidropdown(app.UIFigure);
            app.Slot11.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Fortification', 'Warden''s Eye', 'Vanguard', 'Lightning Rod', 'Overcharged', 'Anti-Tower Socks', 'Gusto', 'Phreakish Gusto', 'Super Mech Armor', 'Super Mech Power Field', 'Turret Plating', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Fire at Will', 'Death''s Daughter', 'Raise Morale', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang', 'Sandshrike''s Claw', 'Syzygy', 'Draktharr''s Shadowcarver', 'Turbocharged Hexperiment', 'Forgefire Crest', 'Rimeforged Grasp', 'Typhoon', 'Wyrmfallen Sacrifice', 'Bloodward', 'Icathia''s Curse', 'Vespertide', 'Upgraded Aeropack', 'Liandry''s Lament', 'Eye of Luden', 'Eternal Winter', 'Ceaseless Hunger', 'Dreamshatter', 'Deicide', 'Infinity Force', 'Reliquary of the Golden Dawn', 'Shurelya''s Requiem', 'Starcaster', 'Seat of Command', ''};
            app.Slot11.Editable = 'on';
            app.Slot11.ValueChangedFcn = createCallbackFcn(app, @Inv11, true);
            app.Slot11.Value = 'None';
            app.Slot12 = uidropdown(app.UIFigure);
            app.Slot12.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Fortification', 'Warden''s Eye', 'Vanguard', 'Lightning Rod', 'Overcharged', 'Anti-Tower Socks', 'Gusto', 'Phreakish Gusto', 'Super Mech Armor', 'Super Mech Power Field', 'Turret Plating', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Fire at Will', 'Death''s Daughter', 'Raise Morale', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang', 'Sandshrike''s Claw', 'Syzygy', 'Draktharr''s Shadowcarver', 'Turbocharged Hexperiment', 'Forgefire Crest', 'Rimeforged Grasp', 'Typhoon', 'Wyrmfallen Sacrifice', 'Bloodward', 'Icathia''s Curse', 'Vespertide', 'Upgraded Aeropack', 'Liandry''s Lament', 'Eye of Luden', 'Eternal Winter', 'Ceaseless Hunger', 'Dreamshatter', 'Deicide', 'Infinity Force', 'Reliquary of the Golden Dawn', 'Shurelya''s Requiem', 'Starcaster', 'Seat of Command', ''};
            app.Slot12.Editable = 'on';
            app.Slot12.ValueChangedFcn = createCallbackFcn(app, @Inv12, true);
            app.Slot12.Value = 'None';
            app.Slot13 = uidropdown(app.UIFigure);
            app.Slot13.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Fortification', 'Warden''s Eye', 'Vanguard', 'Lightning Rod', 'Overcharged', 'Anti-Tower Socks', 'Gusto', 'Phreakish Gusto', 'Super Mech Armor', 'Super Mech Power Field', 'Turret Plating', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Fire at Will', 'Death''s Daughter', 'Raise Morale', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang', 'Sandshrike''s Claw', 'Syzygy', 'Draktharr''s Shadowcarver', 'Turbocharged Hexperiment', 'Forgefire Crest', 'Rimeforged Grasp', 'Typhoon', 'Wyrmfallen Sacrifice', 'Bloodward', 'Icathia''s Curse', 'Vespertide', 'Upgraded Aeropack', 'Liandry''s Lament', 'Eye of Luden', 'Eternal Winter', 'Ceaseless Hunger', 'Dreamshatter', 'Deicide', 'Infinity Force', 'Reliquary of the Golden Dawn', 'Shurelya''s Requiem', 'Starcaster', 'Seat of Command', ''};
            app.Slot13.Editable = 'on';
            app.Slot13.ValueChangedFcn = createCallbackFcn(app, @Inv13, true);
            app.Slot13.Value = 'None';
            app.Slot14 = uidropdown(app.UIFigure);
            app.Slot14.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Fortification', 'Warden''s Eye', 'Vanguard', 'Lightning Rod', 'Overcharged', 'Anti-Tower Socks', 'Gusto', 'Phreakish Gusto', 'Super Mech Armor', 'Super Mech Power Field', 'Turret Plating', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Fire at Will', 'Death''s Daughter', 'Raise Morale', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang', 'Sandshrike''s Claw', 'Syzygy', 'Draktharr''s Shadowcarver', 'Turbocharged Hexperiment', 'Forgefire Crest', 'Rimeforged Grasp', 'Typhoon', 'Wyrmfallen Sacrifice', 'Bloodward', 'Icathia''s Curse', 'Vespertide', 'Upgraded Aeropack', 'Liandry''s Lament', 'Eye of Luden', 'Eternal Winter', 'Ceaseless Hunger', 'Dreamshatter', 'Deicide', 'Infinity Force', 'Reliquary of the Golden Dawn', 'Shurelya''s Requiem', 'Starcaster', 'Seat of Command', ''};
            app.Slot14.Editable = 'on';
            app.Slot14.ValueChangedFcn = createCallbackFcn(app, @Inv14, true);
            app.Slot14.Value = 'None';
            app.Slot15 = uidropdown(app.UIFigure);
            app.Slot15.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Fortification', 'Warden''s Eye', 'Vanguard', 'Lightning Rod', 'Overcharged', 'Anti-Tower Socks', 'Gusto', 'Phreakish Gusto', 'Super Mech Armor', 'Super Mech Power Field', 'Turret Plating', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Fire at Will', 'Death''s Daughter', 'Raise Morale', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang', 'Sandshrike''s Claw', 'Syzygy', 'Draktharr''s Shadowcarver', 'Turbocharged Hexperiment', 'Forgefire Crest', 'Rimeforged Grasp', 'Typhoon', 'Wyrmfallen Sacrifice', 'Bloodward', 'Icathia''s Curse', 'Vespertide', 'Upgraded Aeropack', 'Liandry''s Lament', 'Eye of Luden', 'Eternal Winter', 'Ceaseless Hunger', 'Dreamshatter', 'Deicide', 'Infinity Force', 'Reliquary of the Golden Dawn', 'Shurelya''s Requiem', 'Starcaster', 'Seat of Command', ''};
            app.Slot15.Editable = 'on';
            app.Slot15.ValueChangedFcn = createCallbackFcn(app, @Inv15, true);
            app.Slot15.Value = 'None';
            app.Slot16 = uidropdown(app.UIFigure);
            app.Slot16.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Fortification', 'Warden''s Eye', 'Vanguard', 'Lightning Rod', 'Overcharged', 'Anti-Tower Socks', 'Gusto', 'Phreakish Gusto', 'Super Mech Armor', 'Super Mech Power Field', 'Turret Plating', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Fire at Will', 'Death''s Daughter', 'Raise Morale', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang', 'Sandshrike''s Claw', 'Syzygy', 'Draktharr''s Shadowcarver', 'Turbocharged Hexperiment', 'Forgefire Crest', 'Rimeforged Grasp', 'Typhoon', 'Wyrmfallen Sacrifice', 'Bloodward', 'Icathia''s Curse', 'Vespertide', 'Upgraded Aeropack', 'Liandry''s Lament', 'Eye of Luden', 'Eternal Winter', 'Ceaseless Hunger', 'Dreamshatter', 'Deicide', 'Infinity Force', 'Reliquary of the Golden Dawn', 'Shurelya''s Requiem', 'Starcaster', 'Seat of Command', ''};
            app.Slot16.Editable = 'on';
            app.Slot16.ValueChangedFcn = createCallbackFcn(app, @Inv16, true);
            app.Slot16.Value = 'None';
            app.Slot21 = uidropdown(app.UIFigure);
            app.Slot21.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Fortification', 'Warden''s Eye', 'Vanguard', 'Lightning Rod', 'Overcharged', 'Anti-Tower Socks', 'Gusto', 'Phreakish Gusto', 'Super Mech Armor', 'Super Mech Power Field', 'Turret Plating', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Fire at Will', 'Death''s Daughter', 'Raise Morale', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang', 'Sandshrike''s Claw', 'Syzygy', 'Draktharr''s Shadowcarver', 'Turbocharged Hexperiment', 'Forgefire Crest', 'Rimeforged Grasp', 'Typhoon', 'Wyrmfallen Sacrifice', 'Bloodward', 'Icathia''s Curse', 'Vespertide', 'Upgraded Aeropack', 'Liandry''s Lament', 'Eye of Luden', 'Eternal Winter', 'Ceaseless Hunger', 'Dreamshatter', 'Deicide', 'Infinity Force', 'Reliquary of the Golden Dawn', 'Shurelya''s Requiem', 'Starcaster', 'Seat of Command', ''};
            app.Slot21.Editable = 'on';
            app.Slot21.ValueChangedFcn = createCallbackFcn(app, @Inv21, true);
            app.Slot21.Value = 'None';
            app.Slot22 = uidropdown(app.UIFigure);
            app.Slot22.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Fortification', 'Warden''s Eye', 'Vanguard', 'Lightning Rod', 'Overcharged', 'Anti-Tower Socks', 'Gusto', 'Phreakish Gusto', 'Super Mech Armor', 'Super Mech Power Field', 'Turret Plating', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Fire at Will', 'Death''s Daughter', 'Raise Morale', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang', 'Sandshrike''s Claw', 'Syzygy', 'Draktharr''s Shadowcarver', 'Turbocharged Hexperiment', 'Forgefire Crest', 'Rimeforged Grasp', 'Typhoon', 'Wyrmfallen Sacrifice', 'Bloodward', 'Icathia''s Curse', 'Vespertide', 'Upgraded Aeropack', 'Liandry''s Lament', 'Eye of Luden', 'Eternal Winter', 'Ceaseless Hunger', 'Dreamshatter', 'Deicide', 'Infinity Force', 'Reliquary of the Golden Dawn', 'Shurelya''s Requiem', 'Starcaster', 'Seat of Command', ''};
            app.Slot22.Editable = 'on';
            app.Slot22.ValueChangedFcn = createCallbackFcn(app, @Inv22, true);
            app.Slot22.Value = 'None';
            app.Slot23 = uidropdown(app.UIFigure);
            app.Slot23.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Fortification', 'Warden''s Eye', 'Vanguard', 'Lightning Rod', 'Overcharged', 'Anti-Tower Socks', 'Gusto', 'Phreakish Gusto', 'Super Mech Armor', 'Super Mech Power Field', 'Turret Plating', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Fire at Will', 'Death''s Daughter', 'Raise Morale', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang', 'Sandshrike''s Claw', 'Syzygy', 'Draktharr''s Shadowcarver', 'Turbocharged Hexperiment', 'Forgefire Crest', 'Rimeforged Grasp', 'Typhoon', 'Wyrmfallen Sacrifice', 'Bloodward', 'Icathia''s Curse', 'Vespertide', 'Upgraded Aeropack', 'Liandry''s Lament', 'Eye of Luden', 'Eternal Winter', 'Ceaseless Hunger', 'Dreamshatter', 'Deicide', 'Infinity Force', 'Reliquary of the Golden Dawn', 'Shurelya''s Requiem', 'Starcaster', 'Seat of Command', ''};
            app.Slot23.Editable = 'on';
            app.Slot23.ValueChangedFcn = createCallbackFcn(app, @Inv23, true);
            app.Slot23.Value = 'None';
            app.Slot24 = uidropdown(app.UIFigure);
            app.Slot24.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Fortification', 'Warden''s Eye', 'Vanguard', 'Lightning Rod', 'Overcharged', 'Anti-Tower Socks', 'Gusto', 'Phreakish Gusto', 'Super Mech Armor', 'Super Mech Power Field', 'Turret Plating', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Fire at Will', 'Death''s Daughter', 'Raise Morale', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang', 'Sandshrike''s Claw', 'Syzygy', 'Draktharr''s Shadowcarver', 'Turbocharged Hexperiment', 'Forgefire Crest', 'Rimeforged Grasp', 'Typhoon', 'Wyrmfallen Sacrifice', 'Bloodward', 'Icathia''s Curse', 'Vespertide', 'Upgraded Aeropack', 'Liandry''s Lament', 'Eye of Luden', 'Eternal Winter', 'Ceaseless Hunger', 'Dreamshatter', 'Deicide', 'Infinity Force', 'Reliquary of the Golden Dawn', 'Shurelya''s Requiem', 'Starcaster', 'Seat of Command', ''};
            app.Slot24.Editable = 'on';
            app.Slot24.ValueChangedFcn = createCallbackFcn(app, @Inv24, true);
            app.Slot24.Value = 'None';
            app.Slot25 = uidropdown(app.UIFigure);
            app.Slot25.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Fortification', 'Warden''s Eye', 'Vanguard', 'Lightning Rod', 'Overcharged', 'Anti-Tower Socks', 'Gusto', 'Phreakish Gusto', 'Super Mech Armor', 'Super Mech Power Field', 'Turret Plating', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Fire at Will', 'Death''s Daughter', 'Raise Morale', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang', 'Sandshrike''s Claw', 'Syzygy', 'Draktharr''s Shadowcarver', 'Turbocharged Hexperiment', 'Forgefire Crest', 'Rimeforged Grasp', 'Typhoon', 'Wyrmfallen Sacrifice', 'Bloodward', 'Icathia''s Curse', 'Vespertide', 'Upgraded Aeropack', 'Liandry''s Lament', 'Eye of Luden', 'Eternal Winter', 'Ceaseless Hunger', 'Dreamshatter', 'Deicide', 'Infinity Force', 'Reliquary of the Golden Dawn', 'Shurelya''s Requiem', 'Starcaster', 'Seat of Command', ''};
            app.Slot25.Editable = 'on';
            app.Slot25.ValueChangedFcn = createCallbackFcn(app, @Inv25, true);
            app.Slot25.Value = 'None';
            app.Slot26 = uidropdown(app.UIFigure);

            app.Slot26.Items = {'None', 'Boots', 'Faerie Charm', 'Rejuvenation Bead', 'Giant''s Belt', 'Cloak of Agility', 'Blasting Wand', 'Sapphire Crystal', 'Ruby Crystal', 'Cloth Armor', 'Chain Vest', 'Null-Magic Mantle', 'Emberknife', 'Long Sword', 'Pickaxe', 'B. F. Sword', 'Hailblade', 'Dagger', 'Recurve Bow', 'Amplifying Tome', 'Vampiric Scepter', 'Doran''s Shield', 'Doran''s Blade', 'Doran''s Ring', 'Negatron Cloak', 'Needlessly Large Rod', 'Dark Seal', 'Cull', 'Fortification', 'Warden''s Eye', 'Vanguard', 'Lightning Rod', 'Overcharged', 'Anti-Tower Socks', 'Gusto', 'Phreakish Gusto', 'Super Mech Armor', 'Super Mech Power Field', 'Turret Plating', 'Health Potion', 'Total Biscuit of Everlasting Will', 'Kircheis Shard', 'Refillable Potion', 'Corrupting Potion', 'Guardian''s Horn', 'Poro-Snax', 'Control Ward', 'Shurelya''s Battlesong', 'Elixir of Iron', 'Elixir of Sorcery', 'Elixir of Wrath', 'Minion Dematerializer', 'Commencing Stopwatch', 'Stopwatch', 'Broken Stopwatch', 'Perfectly Timed Stopwatch', 'Broken Stopwatch', 'Abyssal Mask', 'Archangel''s Staff', 'Manamune', 'Berserker''s Greaves', 'Boots of Swiftness', 'Chemtech Putrifier', 'Sorcerer''s Shoes', 'Glacial Buckler', 'Guardian Angel', 'Infinity Edge', 'Mortal Reminder', 'Last Whisper', 'Lord Dominik''s Regards', 'Seraph''s Embrace', 'Mejai''s Soulstealer', 'Muramana', 'Muramana', 'Phage', 'Phantom Dancer', 'Plated Steelcaps', 'Seraph''s Embrace', 'Zeke''s Convergence', 'Hearthbound Axe', 'Sterak''s Gage', 'Sheen', 'Spirit Visage', 'Winged Moonplate', 'Kindlegem', 'Sunfire Aegis', 'Tear of the Goddess', 'Black Cleaver', 'Bloodthirster', 'Ravenous Hydra', 'Thornmail', 'Bramble Vest', 'Tiamat', 'Trinity Force', 'Warden''s Mail', 'Warmog''s Armor', 'Runaan''s Hurricane', 'Zeal', 'Rabadon''s Deathcap', 'Wit''s End', 'Rapid Firecannon', 'Stormrazor', 'Lich Bane', 'Banshee''s Veil', 'Aegis of the Legion', 'Redemption', 'Fiendish Codex', 'Knight''s Vow', 'Frozen Heart', 'Mercury''s Treads', 'Guardian''s Orb', 'Aether Wisp', 'Forbidden Idol', 'Nashor''s Tooth', 'Rylai''s Crystal Scepter', 'Mobility Boots', 'Executioner''s Calling', 'Guinsoo''s Rageblade', 'Caulfield''s Warhammer', 'Serrated Dirk', 'Void Staff', 'Mercurial Scimitar', 'Quicksilver Sash', 'Youmuu''s Ghostblade', 'Randuin''s Omen', 'Hextech Alternator', 'Hextech Rocketbelt', 'Blade of the Ruined King', 'Hexdrinker', 'Maw of Malmortius', 'Zhonya''s Hourglass', 'Ionian Boots of Lucidity', 'Morellonomicon', 'Guardian''s Blade', 'Umbral Glaive', 'Sanguine Blade', 'Guardian''s Hammer', 'Locket of the Iron Solari', 'Seeker''s Armguard', 'Gargoyle Stoneplate', 'Spectre''s Cowl', 'Mikael''s Blessing', 'Scarecrow Effigy', 'Stealth Ward', 'Farsight Alteration', 'Oracle Lens', '''Your Cut''', 'Ardent Censer', 'Essence Reaver', 'Eye of the Herald', 'Dead Man''s Plate', 'Titanic Hydra', 'Crystalline Bracer', 'Lost Chapter', 'Edge of Night', 'Spellthief''s Edge', 'Frostfang', 'Shard of True Ice', 'Steel Shoulderguards', 'Runesteel Spaulders', 'Pauldrons of Whiterock', 'Relic Shield', 'Targon''s Buckler', 'Bulwark of the Mountain', 'Spectral Sickle', 'Harrowing Crescent', 'Black Mist Scythe', 'Fire at Will', 'Death''s Daughter', 'Raise Morale', 'Oblivion Orb', 'Imperial Mandate', 'Force of Nature', 'The Golden Spatula', 'Horizon Focus', 'Cosmic Drive', 'Blighting Jewel', 'Verdant Barrier', 'Riftmaker', 'Leeching Leer', 'Night Harvester', 'Demonic Embrace', 'Watchful Wardstone', 'Stirring Wardstone', 'Bandleglass Mirror', 'Vigilant Wardstone', 'Ironspike Whip', 'Silvermere Dawn', 'Death''s Dance', 'Chempunk Chainsword', 'Staff of Flowing Water', 'Moonstone Renewer', 'Goredrinker', 'Stridebreaker', 'Divine Sunderer', 'Liandry''s Anguish', 'Luden''s Tempest', 'Everfrost', 'Bami''s Cinder', 'Frostfire Gauntlet', 'Turbo Chemtank', 'Noonquiver', 'Galeforce', 'Kraken Slayer', 'Immortal Shieldbow', 'Navori Quickblades', 'The Collector', 'Rageknife', 'Duskblade of Draktharr', 'Eclipse', 'Prowler''s Claw', 'Serylda''s Grudge', 'Serpent''s Fang', 'Sandshrike''s Claw', 'Syzygy', 'Draktharr''s Shadowcarver', 'Turbocharged Hexperiment', 'Forgefire Crest', 'Rimeforged Grasp', 'Typhoon', 'Wyrmfallen Sacrifice', 'Bloodward', 'Icathia''s Curse', 'Vespertide', 'Upgraded Aeropack', 'Liandry''s Lament', 'Eye of Luden', 'Eternal Winter', 'Ceaseless Hunger', 'Dreamshatter', 'Deicide', 'Infinity Force', 'Reliquary of the Golden Dawn', 'Shurelya''s Requiem', 'Starcaster', 'Seat of Command', ''};
            app.Slot26.Editable = 'on';
            app.Slot26.ValueChangedFcn = createCallbackFcn(app, @Inv26, true);
            app.Slot26.Value = 'None';
            app.ChampionName = uidropdown(app.UIFigure);
            app.ChampionName.Items = {'Aatrox', 'Ahri', 'Akali', 'Alistar', 'Amumu', 'Anivia', 'Annie', 'Aphelios', 'Ashe', 'AurelionSol', 'Azir', 'Bard', 'Blitzcrank', 'Brand', 'Braum', 'Caitlyn', 'Camille', 'Cassiopeia', 'Chogath', 'Corki', 'Darius', 'Diana', 'DrMundo', 'Draven', 'Ekko', 'Elise', 'Evelynn', 'Ezreal', 'Fiddlesticks', 'Fiora', 'Fizz', 'Galio', 'Gangplank', 'Garen', 'Gnar', 'Gragas', 'Graves', 'Hecarim', 'Heimerdinger', 'Illaoi', 'Irelia', 'Ivern', 'Janna', 'JarvanIV', 'Jax', 'Jayce', 'Jhin', 'Jinx', 'Kaisa', 'Kalista', 'Karma', 'Karthus', 'Kassadin', 'Katarina', 'Kayle', 'Kayn', 'Kennen', 'Khazix', 'Kindred', 'Kled', 'KogMaw', 'Leblanc', 'LeeSin', 'Leona', 'Lillia', 'Lissandra', 'Lucian', 'Lulu', 'Lux', 'Malphite', 'Malzahar', 'Maokai', 'MasterYi', 'MissFortune', 'Mordekaiser', 'Morgana', 'Nami', 'Nasus', 'Nautilus', 'Neeko', 'Nidalee', 'Nocturne', 'Nunu', 'Olaf', 'Orianna', 'Ornn', 'Pantheon', 'Poppy', 'Pyke', 'Qiyana', 'Quinn', 'Rakan', 'Rammus', 'RekSai', 'Rell', 'Renekton', 'Rengar', 'Riven', 'Rumble', 'Ryze', 'Samira', 'Sejuani', 'Senna', 'Seraphine', 'Sett', 'Shaco', 'Shen', 'Shyvana', 'Singed', 'Sion', 'Sivir', 'Skarner', 'Sona', 'Soraka', 'Swain', 'Sylas', 'Syndra', 'TahmKench', 'Taliyah', 'Talon', 'Taric', 'Teemo', 'Thresh', 'Tristana', 'Trundle', 'Tryndamere', 'TwistedFate', 'Twitch', 'Udyr', 'Urgot', 'Varus', 'Vayne', 'Veigar', 'Velkoz', 'Vi', 'Viktor', 'Vladimir', 'Volibear', 'Warwick', 'MonkeyKing', 'Xayah', 'Xerath', 'XinZhao', 'Yasuo', 'Yone', 'Yorick', 'Yuumi', 'Zac', 'Zed', 'Ziggs', 'Zilean', 'Zoe', 'Zyra'};
            app.ChampionName.Editable = 'on';
            app.ChampionName.ValueChangedFcn = createCallbackFcn(app, @ChampGen1, true);
            app.ChampionName.Value = 'Lux';
            app.ChampionName2 = uidropdown(app.UIFigure);
            app.ChampionName2.Items = {'Aatrox', 'Ahri', 'Akali', 'Alistar', 'Amumu', 'Anivia', 'Annie', 'Aphelios', 'Ashe', 'AurelionSol', 'Azir', 'Bard', 'Blitzcrank', 'Brand', 'Braum', 'Caitlyn', 'Camille', 'Cassiopeia', 'Chogath', 'Corki', 'Darius', 'Diana', 'DrMundo', 'Draven', 'Ekko', 'Elise', 'Evelynn', 'Ezreal', 'Fiddlesticks', 'Fiora', 'Fizz', 'Galio', 'Gangplank', 'Garen', 'Gnar', 'Gragas', 'Graves', 'Hecarim', 'Heimerdinger', 'Illaoi', 'Irelia', 'Ivern', 'Janna', 'JarvanIV', 'Jax', 'Jayce', 'Jhin', 'Jinx', 'Kaisa', 'Kalista', 'Karma', 'Karthus', 'Kassadin', 'Katarina', 'Kayle', 'Kayn', 'Kennen', 'Khazix', 'Kindred', 'Kled', 'KogMaw', 'Leblanc', 'LeeSin', 'Leona', 'Lillia', 'Lissandra', 'Lucian', 'Lulu', 'Lux', 'Malphite', 'Malzahar', 'Maokai', 'MasterYi', 'MissFortune', 'Mordekaiser', 'Morgana', 'Nami', 'Nasus', 'Nautilus', 'Neeko', 'Nidalee', 'Nocturne', 'Nunu', 'Olaf', 'Orianna', 'Ornn', 'Pantheon', 'Poppy', 'Pyke', 'Qiyana', 'Quinn', 'Rakan', 'Rammus', 'RekSai', 'Rell', 'Renekton', 'Rengar', 'Riven', 'Rumble', 'Ryze', 'Samira', 'Sejuani', 'Senna', 'Seraphine', 'Sett', 'Shaco', 'Shen', 'Shyvana', 'Singed', 'Sion', 'Sivir', 'Skarner', 'Sona', 'Soraka', 'Swain', 'Sylas', 'Syndra', 'TahmKench', 'Taliyah', 'Talon', 'Taric', 'Teemo', 'Thresh', 'Tristana', 'Trundle', 'Tryndamere', 'TwistedFate', 'Twitch', 'Udyr', 'Urgot', 'Varus', 'Vayne', 'Veigar', 'Velkoz', 'Vi', 'Viktor', 'Vladimir', 'Volibear', 'Warwick', 'MonkeyKing', 'Xayah', 'Xerath', 'XinZhao', 'Yasuo', 'Yone', 'Yorick', 'Yuumi', 'Zac', 'Zed', 'Ziggs', 'Zilean', 'Zoe', 'Zyra'};
            app.ChampionName2.Editable = 'on';
            app.ChampionName2.ValueChangedFcn = createCallbackFcn(app, @ChampGen2, true);
            app.ChampionName2.Value = 'Malzahar';
            app.LvlDropDownLabel = uilabel(app.UIFigure);
            app.LvlDropDownLabel.Text = 'Lvl.';
            app.LvlDrop1 = uidropdown(app.UIFigure);
            app.LvlDrop1.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18'};
            app.LvlDrop1.Editable = 'on';
            app.LvlDrop1.ValueChangedFcn = createCallbackFcn(app, @LevelUp1, true);
            app.LvlDrop1.Value = '5';
            app.LvlDropDownLabel_2 = uilabel(app.UIFigure);
            app.LvlDropDownLabel_2.Text = 'Lvl.';
            app.LvlDrop2 = uidropdown(app.UIFigure);
            app.LvlDrop2.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18'};
            app.LvlDrop2.Editable = 'on';
            app.LvlDrop2.ValueChangedFcn = createCallbackFcn(app, @LevelUp2, true);
            app.LvlDrop2.Value = '7';
            app.QSpinnerLabel = uilabel(app.UIFigure);
            app.QSpinnerLabel.Text = 'Q';
            app.QSpinner1 = uispinner(app.UIFigure);
            app.QSpinner1.ValueChangedFcn = createCallbackFcn(app, @AbiUpdate, true);
            app.QSpinner1.Value = 1;
            app.ESpinnerLabel = uilabel(app.UIFigure);
            app.ESpinnerLabel.Text = 'E';
            app.ESpinner1 = uispinner(app.UIFigure);
            app.ESpinner1.ValueChangedFcn = createCallbackFcn(app, @AbiUpdate, true);
            app.WSpinnerLabel = uilabel(app.UIFigure);
            app.WSpinnerLabel.Text = 'W';
            app.WSpinner1 = uispinner(app.UIFigure);
            app.WSpinner1.ValueChangedFcn = createCallbackFcn(app, @AbiUpdate, true);
            app.RSpinnerLabel = uilabel(app.UIFigure);
            app.RSpinnerLabel.Text = 'R';
            app.RSpinner1 = uispinner(app.UIFigure);
            app.RSpinner1.Limits = [0 1];
            app.RSpinner1.ValueChangedFcn = createCallbackFcn(app, @AbiUpdate, true);
            app.QSpinner_2Label = uilabel(app.UIFigure);
            app.QSpinner_2Label.Text = 'Q';
            app.QSpinner2 = uispinner(app.UIFigure);
            app.QSpinner2.ValueChangedFcn = createCallbackFcn(app, @AbiUpdate, true);
            app.ESpinner_2Label = uilabel(app.UIFigure);
            app.ESpinner_2Label.Text = 'E';
            app.ESpinner2 = uispinner(app.UIFigure);
            app.ESpinner2.ValueChangedFcn = createCallbackFcn(app, @AbiUpdate, true);
            app.ESpinner2.Value = 1;
            app.WSpinner_2Label = uilabel(app.UIFigure);
            app.WSpinner_2Label.Text = 'W';
            app.WSpinner2 = uispinner(app.UIFigure);
            app.WSpinner2.ValueChangedFcn = createCallbackFcn(app, @AbiUpdate, true);
            app.RSpinner_2Label = uilabel(app.UIFigure);
            app.RSpinner_2Label.Text = 'R';
            app.RSpinner2 = uispinner(app.UIFigure);
            app.RSpinner2.ValueChangedFcn = createCallbackFcn(app, @AbiUpdate, true);
            app.abi1 = uilabel(app.UIFigure);
            app.abi1.Text = '1 point to spend!';
            app.abi2 = uilabel(app.UIFigure);
            app.abi2.Text = '1 point to spend!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DoubleChamp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end